classdef dlgConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lblVerifyUIFigure   matlab.ui.Figure
        CancelButton        matlab.ui.control.Button
        VerifythePMUconfigurationforthesefilesLabel  matlab.ui.control.Label
        F0DropDownLabel     matlab.ui.control.Label
        F0DropDown          matlab.ui.control.DropDown
        FsDropDownLabel     matlab.ui.control.Label
        FsDropDown          matlab.ui.control.DropDown
        ClassDropDownLabel  matlab.ui.control.Label
        ClassDropDown       matlab.ui.control.DropDown
        ContinueButton      matlab.ui.control.Button
        pathLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp  % app that called this dialog
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, F0, Fs, Class, Path)
            app.CallingApp = mainApp;
            app.F0DropDown.Value = F0;

            if strcmp(F0,'60')
                app.FsDropDown.Items = {'10','12','15','20','30','60','120'};
            else
                app.FsDropDown.Items = {'10','25','50','100'};
            end
            app.FsDropDown.Value = Fs;
            app.ClassDropDown.Value=Class;
            app.pathLabel.Text = Path;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.CallingApp.F0 = str2num(app.F0DropDown.Value);
            app.CallingApp.Fs = str2num(app.FsDropDown.Value);
            app.CallingApp.PmuClass= app.ClassDropDown.Value;
            delete(app)
        end

        % Value changed function: F0DropDown
        function F0DropDownValueChanged(app, event)
            value = app.F0DropDown.Value;
            if strcmp(value,'60')
                app.FsDropDown.Items = {'10','12','15','20','30','60','120'};
            else
                app.FsDropDown.Items = {'10','25','50','100'};               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lblVerifyUIFigure = uifigure('Visible', 'off');
            app.lblVerifyUIFigure.Name = 'lblVerify';
            app.CancelButton = uibutton(app.lblVerifyUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.VerifythePMUconfigurationforthesefilesLabel = uilabel(app.lblVerifyUIFigure);
            app.VerifythePMUconfigurationforthesefilesLabel.Text = 'Verify the PMU configuration for these files';
            app.F0DropDownLabel = uilabel(app.lblVerifyUIFigure);
            app.F0DropDownLabel.Text = 'F0';
            app.F0DropDown = uidropdown(app.lblVerifyUIFigure);
            app.F0DropDown.Items = {'50', '60'};
            app.F0DropDown.ValueChangedFcn = createCallbackFcn(app, @F0DropDownValueChanged, true);
            app.F0DropDown.Value = '50';
            app.FsDropDownLabel = uilabel(app.lblVerifyUIFigure);
            app.FsDropDownLabel.Text = 'Fs';
            app.FsDropDown = uidropdown(app.lblVerifyUIFigure);
            app.FsDropDown.Items = {'10', '25', '50', '100'};
            app.FsDropDown.Value = '50';
            app.ClassDropDownLabel = uilabel(app.lblVerifyUIFigure);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.lblVerifyUIFigure);
            app.ClassDropDown.Items = {'M', 'P'};
            app.ClassDropDown.Value = 'M';
            app.ContinueButton = uibutton(app.lblVerifyUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.pathLabel = uilabel(app.lblVerifyUIFigure);
            app.pathLabel.Text = 'path';
            % Show the figure after all components are created
            app.lblVerifyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dlgConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.lblVerifyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lblVerifyUIFigure)
        end
    end
end